classdef lightGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VerlassenButton              matlab.ui.control.Button
        StopFarberkennungButton      matlab.ui.control.Button
        StartFarberkennungButton     matlab.ui.control.Button
        LetzteFarbeLabel             matlab.ui.control.Label
        FarberkennungLampLabel       matlab.ui.control.Label
        FarberkennungLamp            matlab.ui.control.Lamp
        LaufbandSwitchLabel          matlab.ui.control.Label
        LaufbandSwitch               matlab.ui.control.Switch
        RotLampLabel                 matlab.ui.control.Label
        RotLamp                      matlab.ui.control.Lamp
        RotCounter                   matlab.ui.control.Label
        GruenCounter                 matlab.ui.control.Label
        GrnLabel                     matlab.ui.control.Label
        GruenLamp                    matlab.ui.control.Lamp
        LetzteFarbeLamp              matlab.ui.control.Lamp
        LaufbandgeschwindigkeitKnobLabel  matlab.ui.control.Label
        LaufbandgeschwindigkeitKnob  matlab.ui.control.Knob
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        config          % Speichern einiger Konfigurationsdaten
        brickObj        
        timerObj
        
    end
    
    methods (Access = private)
        function readColorTimerCallback(app, timerObj, event)            
            userData = get(timerObj, 'UserData');
            currentMeasurementData = userData.colorData;
            userData.colorValue = 0;
            
            %% Stage 2: Read color value and decide when to start the moto
            if userData.colorValue ~= userData.colorSensor.value
      
               userData.colorValue = userData.colorSensor.value;
            
               switch userData.colorValue
                    case 2 % Weiß
                    case 3 % Blau, Grün
                        userData.bgCounter = userData.bgCounter + 1;
                        app.LetzteFarbeLamp.Color = [0 1 0];
                        pause(0.45);
                        motor_start(userData.bgMotor);
                    case 5 % Orange, Rot
                        userData.ryCounter = userData.ryCounter + 1;
                        app.LetzteFarbeLamp.Color = [1 0 0];
                        pause(1.30);
                        motor_start(userData.ryMotor);
                    case 7 % Gelb
                end
            end
            
            set(timerObj, 'UserData', userData);
            display(userData.colorValue);
            app.GruenCounter.Text = num2str(userData.bgCounter);
            app.RotCounter.Text = num2str(userData.ryCounter);
            x_val = [1,2];
            y_val = [userData.bgCounter, userData.ryCounter];
            
            bar(app.UIAxes, x_val, y_val);
        end
        
        function readColorTimerStartCallback(app, timerObj, event)
            userData = get(timerObj, 'UserData');
            app.FarberkennungLamp.Color = [0 1 0];
            disp('Timer wurde gestartet');
        end
        
        function readColorTimerStopCallback(app, timerObj, event)
            userData = get(timerObj, 'UserData');
            app.FarberkennungLamp.Color = [1 0 0];
            disp('Timer wurde angehalten');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Speichern einiger Konfigurationsdaten in den app-Properties
            app.config.timerPeriod          = 0.1;
            app.config.timerExecutionMode   = 'fixedDelay';
            app.config.brickConnectionType  = 'usb';
            
            % Bluetooth initialisieren und Verbindung oeffnen
            try
                app.brickObj = connect();
            catch errinfo
                disp('Verbindung konnte nicht hergestellt werden');
                disp(errinfo);
            end
            
            % Daten fuer UserData Eigenschaft des Timer-Objektes vorbereiten
            programData.colorData = zeros(100,1);
            programData.brickObj = app.brickObj;
            
            [programData.conveyor, programData.bgMotor, programData.ryMotor] = init_motors(app.brickObj); 
            
            programData.colorSensor = init_sensors(app.brickObj);
            programData.bgCounter = 0;
            programData.ryCounter = 0;
            programData.colorValue = 0;
            
            % Timer-Objekt erzeugen, konfigurieren und in den app-Properties speichern
            app.timerObj = timer(...
                'Period',        app.config.timerPeriod, ...
                'ExecutionMode', app.config.timerExecutionMode, ...
                'UserData',      programData, ...
                'StartFcn',     @app.readColorTimerStartCallback, ...
                'StopFcn',      @app.readColorTimerStopCallback, ...
                'TimerFcn',     @app.readColorTimerCallback);
            
            app.GruenCounter.Text = '0';
            app.RotCounter.Text = '0';
            
            % Initiales Plotten der Daten (muss man nicht unbedingt machen)
            bar(app.UIAxes, [1,2], [programData.bgCounter, programData.ryCounter]);
            app.UIAxes.YLim = [0 100];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.timerObj);
            
            disconnect(app.brickObj);
            
            delete(app.timerObj);
            delete(app);
        end

        % Button pushed function: StartFarberkennungButton
        function StartFarberkennungButtonPushed(app, event)
            if strcmp(app.timerObj.Running, 'off')
                start(app.timerObj);
            end
            app.FarberkennungLamp.Color = [0 1 0];
        end

        % Button pushed function: StopFarberkennungButton
        function StopFarberkennungButtonPushed(app, event)
            if strcmp(app.timerObj.Running, 'on')
                stop(app.timerObj);
            end
            app.FarberkennungLamp.Color = [1 0 0];
        end

        % Callback function: UIFigure, VerlassenButton
        function QuitButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Value changed function: LaufbandSwitch
        function LaufbandSwitchValueChanged(app, event)
            value = app.LaufbandSwitch.Value;
            userData = get(app.timerObj, 'UserData');
            if strcmp(value, 'An')

                run_conveyor(userData.conveyor);
            else
                stop_conveyor(userData.conveyor);
            end
        end

        % Value changed function: LaufbandgeschwindigkeitKnob
        function LaufbandgeschwindigkeitKnobValueChanged(app, event)
            value = app.LaufbandgeschwindigkeitKnob.Value;
            userData = get(app.timerObj, 'UserData');
            userData.conveyor.power = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.VerlassenButton = uibutton(app.UIFigure, 'push');
            app.VerlassenButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.VerlassenButton.Text = 'Verlassen';
            app.StopFarberkennungButton = uibutton(app.UIFigure, 'push');
            app.StopFarberkennungButton.ButtonPushedFcn = createCallbackFcn(app, @StopFarberkennungButtonPushed, true);
            app.StopFarberkennungButton.Text = 'Stop Farberkennung';
            app.StartFarberkennungButton = uibutton(app.UIFigure, 'push');
            app.StartFarberkennungButton.ButtonPushedFcn = createCallbackFcn(app, @StartFarberkennungButtonPushed, true);
            app.StartFarberkennungButton.Text = {'Start Farberkennung'; ''};
            app.LetzteFarbeLabel = uilabel(app.UIFigure);
            app.LetzteFarbeLabel.Text = 'Letzte Farbe:';
            app.FarberkennungLampLabel = uilabel(app.UIFigure);
            app.FarberkennungLampLabel.Text = 'Farberkennung';
            app.FarberkennungLamp = uilamp(app.UIFigure);
            app.LaufbandSwitchLabel = uilabel(app.UIFigure);
            app.LaufbandSwitchLabel.Text = {'Laufband'; ''};
            app.LaufbandSwitch = uiswitch(app.UIFigure, 'slider');
            app.LaufbandSwitch.Items = {'Aus', 'An'};
            app.LaufbandSwitch.ValueChangedFcn = createCallbackFcn(app, @LaufbandSwitchValueChanged, true);
            app.LaufbandSwitch.Value = 'Aus';
            app.RotLampLabel = uilabel(app.UIFigure);
            app.RotLampLabel.Text = 'Rot:';
            app.RotLamp = uilamp(app.UIFigure);
            app.RotCounter = uilabel(app.UIFigure);
            app.RotCounter.Text = '';
            app.GruenCounter = uilabel(app.UIFigure);
            app.GruenCounter.Text = '';
            app.GrnLabel = uilabel(app.UIFigure);
            app.GrnLabel.Text = 'Grün:';
            app.GruenLamp = uilamp(app.UIFigure);
            app.LetzteFarbeLamp = uilamp(app.UIFigure);
            app.LaufbandgeschwindigkeitKnobLabel = uilabel(app.UIFigure);
            app.LaufbandgeschwindigkeitKnobLabel.Text = 'Laufbandgeschwindigkeit';
            app.LaufbandgeschwindigkeitKnob = uiknob(app.UIFigure, 'continuous');
            app.LaufbandgeschwindigkeitKnob.ValueChangedFcn = createCallbackFcn(app, @LaufbandgeschwindigkeitKnobValueChanged, true);
            app.LaufbandgeschwindigkeitKnob.Value = 20;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Farben')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lightGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end