classdef cubeGUI_without_brick < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RoboterSwitch       matlab.ui.control.Switch
        RoboterSwitchLabel  matlab.ui.control.Label
        ResetButton         matlab.ui.control.StateButton
        RandomButton        matlab.ui.control.StateButton
        ScannenButton       matlab.ui.control.StateButton
        Losungsweg          matlab.ui.control.Label
        LsenButton          matlab.ui.control.StateButton
        StatusLamp          matlab.ui.control.Lamp
        StatusLampLabel     matlab.ui.control.Label
        Button_D2           matlab.ui.control.Button
        Button_B2           matlab.ui.control.Button
        Button_L2           matlab.ui.control.Button
        Button_U2           matlab.ui.control.Button
        Button_R2           matlab.ui.control.Button
        Button_F2           matlab.ui.control.Button
        Button_D            matlab.ui.control.Button
        Button_B            matlab.ui.control.Button
        Button_L            matlab.ui.control.Button
        Button_U            matlab.ui.control.Button
        Button_R            matlab.ui.control.Button
        Button_F            matlab.ui.control.Button
        LampN48             matlab.ui.control.Lamp
        LampN51             matlab.ui.control.Lamp
        LampN54             matlab.ui.control.Lamp
        LampN47             matlab.ui.control.Lamp
        LampN50             matlab.ui.control.Lamp
        LampN53             matlab.ui.control.Lamp
        LampN46             matlab.ui.control.Lamp
        LampN49             matlab.ui.control.Lamp
        LampN52             matlab.ui.control.Lamp
        LampN39             matlab.ui.control.Lamp
        LampN42             matlab.ui.control.Lamp
        LampN45             matlab.ui.control.Lamp
        LampN38             matlab.ui.control.Lamp
        LampN41             matlab.ui.control.Lamp
        LampN44             matlab.ui.control.Lamp
        LampN37             matlab.ui.control.Lamp
        LampN40             matlab.ui.control.Lamp
        LampN43             matlab.ui.control.Lamp
        LampN30             matlab.ui.control.Lamp
        LampN33             matlab.ui.control.Lamp
        LampN36             matlab.ui.control.Lamp
        LampN29             matlab.ui.control.Lamp
        LampN32             matlab.ui.control.Lamp
        LampN35             matlab.ui.control.Lamp
        LampN28             matlab.ui.control.Lamp
        LampN31             matlab.ui.control.Lamp
        LampN34             matlab.ui.control.Lamp
        LampN21             matlab.ui.control.Lamp
        LampN24             matlab.ui.control.Lamp
        LampN27             matlab.ui.control.Lamp
        LampN20             matlab.ui.control.Lamp
        LampN23             matlab.ui.control.Lamp
        LampN26             matlab.ui.control.Lamp
        LampN19             matlab.ui.control.Lamp
        LampN22             matlab.ui.control.Lamp
        LampN25             matlab.ui.control.Lamp
        LampN12             matlab.ui.control.Lamp
        LampN15             matlab.ui.control.Lamp
        LampN18             matlab.ui.control.Lamp
        LampN11             matlab.ui.control.Lamp
        LampN14             matlab.ui.control.Lamp
        LampN17             matlab.ui.control.Lamp
        LampN10             matlab.ui.control.Lamp
        LampN13             matlab.ui.control.Lamp
        LampN16             matlab.ui.control.Lamp
        LampN9              matlab.ui.control.Lamp
        LampN6              matlab.ui.control.Lamp
        LampN3              matlab.ui.control.Lamp
        LampN8              matlab.ui.control.Lamp
        LampN5              matlab.ui.control.Lamp
        LampN2              matlab.ui.control.Lamp
        LampN7              matlab.ui.control.Lamp
        LampN4              matlab.ui.control.Lamp
        LampN1              matlab.ui.control.Lamp
        CubeUIAxis          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        config
        b
        grabber
        rotator
        sensorMotor
        sensor
        lampElements
        cube
        roboterStatus
    end
    
    methods (Access = private)
        function lampList = saveLamps(app, lampList)
           lampList = [lampList, app.LampN1];
           lampList = [lampList, app.LampN2];
           lampList = [lampList, app.LampN3];
           lampList = [lampList, app.LampN4];
           lampList = [lampList, app.LampN5];
           lampList = [lampList, app.LampN6];
           lampList = [lampList, app.LampN7];
           lampList = [lampList, app.LampN8];
           lampList = [lampList, app.LampN9];
           lampList = [lampList, app.LampN10];
           lampList = [lampList, app.LampN11];
           lampList = [lampList, app.LampN12];
           lampList = [lampList, app.LampN13];
           lampList = [lampList, app.LampN14];
           lampList = [lampList, app.LampN15];
           lampList = [lampList, app.LampN16];
           lampList = [lampList, app.LampN17];
           lampList = [lampList, app.LampN18];
           lampList = [lampList, app.LampN19];
           lampList = [lampList, app.LampN20];
           lampList = [lampList, app.LampN21];
           lampList = [lampList, app.LampN22];
           lampList = [lampList, app.LampN23];
           lampList = [lampList, app.LampN24];
           lampList = [lampList, app.LampN25];
           lampList = [lampList, app.LampN26];
           lampList = [lampList, app.LampN27];
           lampList = [lampList, app.LampN28];
           lampList = [lampList, app.LampN29];
           lampList = [lampList, app.LampN30];
           lampList = [lampList, app.LampN31];
           lampList = [lampList, app.LampN32];
           lampList = [lampList, app.LampN33];
           lampList = [lampList, app.LampN34];
           lampList = [lampList, app.LampN35];
           lampList = [lampList, app.LampN36];
           lampList = [lampList, app.LampN37];
           lampList = [lampList, app.LampN38];
           lampList = [lampList, app.LampN39];
           lampList = [lampList, app.LampN40];
           lampList = [lampList, app.LampN41];
           lampList = [lampList, app.LampN42];
           lampList = [lampList, app.LampN43];
           lampList = [lampList, app.LampN44];
           lampList = [lampList, app.LampN45];
           lampList = [lampList, app.LampN46];
           lampList = [lampList, app.LampN47];
           lampList = [lampList, app.LampN48];
           lampList = [lampList, app.LampN49];
           lampList = [lampList, app.LampN50];
           lampList = [lampList, app.LampN51];
           lampList = [lampList, app.LampN52];
           lampList = [lampList, app.LampN53];
           lampList = [lampList, app.LampN54];
        end
        
        function lampColor = getLampColor(app, colorCode)
            switch colorCode
                case 4
                    lampColor = [0 1 0];
                case 2
                    lampColor = [0 0 1];
                case 6
                    lampColor = [1 1 0];
                case 3
                    lampColor = [1 0.41 0.16];
                case 1
                    lampColor = [1 0 0];
                case 5
                    lampColor = [1 1 1];
            end
        end
    
        function colors = getValidLampColors(app, cube)
%             colors = zeros(3,3,6);
%             colors((5-1)*9+1:(5-1)*9+9) = cube((6-1)*9+1:(6-1)*9+9); % Yellow
%             colors((1-1)*9+1:(1-1)*9+9) = cube((5-1)*9+1:(5-1)*9+9); % White
%             colors((4-1)*9+1:(4-1)*9+9) = cube((4-1)*9+1:(4-1)*9+9); % Green
%             colors((6-1)*9+1:(6-1)*9+9) = cube((3-1)*9+1:(3-1)*9+9); % Orange
%             colors((2-1)*9+1:(2-1)*9+9) = cube((2-1)*9+1:(2-1)*9+9); % Blue
%             colors((3-1)*9+1:(3-1)*9+9) = cube((1-1)*9+1:(1-1)*9+9); % Red
            colors = cube;
        end

        function changeLampsOfCube(app, colors)
            for i = 1:54
                app.lampElements(i).Color = getLampColor(app, colors(i));
            end
        end
    
        function plotCube(app, cube)
%             draw3DCube(app.CubeUIAxis, to_faces(cube));
%             view(app.CubeUIAxis, 3);
%             axis(app.CubeUIAxis, "off"); 
%             box(app.CubeUIAxis, "on");
            rubplot(app.cube, app.CubeUIAxis);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Speichern einiger Konfigurationsdaten in den app-Properties
            app.lampElements = [];
            app.lampElements = saveLamps(app, app.lampElements);
            app.roboterStatus = 'Aus';
            
            %[app.b, app.grabber, app.rotator, app.sensorMotor, app.sensor] = connect();
            
            app.cube = randomized_cube(3,0);
            
%           loaded_cube = load('solvable_cube_2.mat', 'cube');
%           app.cube = loaded_cube.cube;

            % Brick Initialisierung
            try
                %app.brick = connect();
            catch errinfo
                disp('Verbindung konnte nicht hergestellt werden');
                disp(errinfo);
            end

            changeLampsOfCube(app, getValidLampColors(app, app.cube));
            plotCube(app, app.cube);
            

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %disconnect(app.b);
            delete(app);
        end

        % Button down function: UIFigure
        function QuitButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: Button_F
        function Button_FPushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'F');
                        
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'F');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_F2
        function Button_F2Pushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'F''');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'F''');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_R
        function Button_RPushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'R');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'R');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_R2
        function Button_R2Pushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'R''');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'R''');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_U
        function Button_UPushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'U');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'U');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_U2
        function Button_U2Pushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'U''');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'U''');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_L
        function Button_LPushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'L');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'L');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_L2
        function Button_L2Pushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'L''');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'L''');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_B
        function Button_BPushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'B');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'B');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_B2
        function Button_B2Pushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'B''');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'B''');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_D
        function Button_DPushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'D');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'D');
            app.StatusLamp.Color = [1 0 0];
        end

        % Button pushed function: Button_D2
        function Button_D2Pushed(app, event)
            app.StatusLamp.Color = [0 0 1];
            cube = move_cube(app.cube, 'D''');
            
            changeLampsOfCube(app, getValidLampColors(app, cube));
            app.cube = cube;
            plotCube(app, app.cube);
            %physical_move_cube(app.grabber, app.rotator, 'D''');
            app.StatusLamp.Color = [1 0 0];
        end

        % Value changed function: RandomButton
        function RandomButtonValueChanged(app, event)
            app.StatusLamp.Color = [0 0 1];
            value = app.RandomButton.Value;
            display(value);
            [app.cube, moves] = randomized_cube(3,35);
            changeLampsOfCube(app, getValidLampColors(app, app.cube));
            plotCube(app, app.cube);
            if strcmp(app.roboterStatus, 'An')
                for i = 1:length(moves)
                    %physical_move_cube(app.grabber, app.rotator, moves{i});
                end
            end

            app.StatusLamp.Color = [1 0 0];
        end

        % Value changed function: LsenButton
        function LsenButtonValueChanged(app, event)
            app.StatusLamp.Color = [0 0 1];
            value = app.LsenButton.Value;
                        
            solved = Solve45(app.cube);
            display(solved);
            
            solved_text = '';
            for i = 1:length(solved)
                solved_text = strcat(solved_text,solved{1,i},' ; ');
            end
            app.Losungsweg.Text = solved_text;
            display(solved_text);
            app.StatusLamp.Color = [1 0 0];
        end

        % Value changed function: ScannenButton
        function ScannenButtonValueChanged(app, event)
            value = app.ScannenButton.Value;
            app.StatusLamp.Color = [0 0 1];
            %cube_new = sensor_scan(app.rotator,app.sensorMotor,app.sensor);
            app.StatusLamp.Color = [1 0 0];
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;
            app.StatusLamp.Color = [0 0 1];
            app.cube = randomized_cube(3,0);
            changeLampsOfCube(app, getValidLampColors(app, app.cube));
            plotCube(app, app.cube);
            app.StatusLamp.Color = [1 0 0];
        end

        % Value changed function: RoboterSwitch
        function RoboterSwitchValueChanged(app, event)
            value = app.RoboterSwitch.Value;
            if strcmp(value, 'An')
                app.roboterStatus = 'An';
            else
                app.roboterStatus = 'Aus';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.CubeUIAxis = uiaxes(app.UIFigure);
            app.LampN1 = uilamp(app.UIFigure);
            app.LampN4 = uilamp(app.UIFigure);
            app.LampN7 = uilamp(app.UIFigure);
            app.LampN2 = uilamp(app.UIFigure);
            app.LampN5 = uilamp(app.UIFigure);
            app.LampN8 = uilamp(app.UIFigure);
            app.LampN3 = uilamp(app.UIFigure);
            app.LampN6 = uilamp(app.UIFigure);
            app.LampN9 = uilamp(app.UIFigure);
            app.LampN16 = uilamp(app.UIFigure);
            app.LampN13 = uilamp(app.UIFigure);
            app.LampN10 = uilamp(app.UIFigure);
            app.LampN17 = uilamp(app.UIFigure);
            app.LampN14 = uilamp(app.UIFigure);
            app.LampN11 = uilamp(app.UIFigure);
            app.LampN18 = uilamp(app.UIFigure);
            app.LampN15 = uilamp(app.UIFigure);
            app.LampN12 = uilamp(app.UIFigure);
            app.LampN25 = uilamp(app.UIFigure);
            app.LampN22 = uilamp(app.UIFigure);
            app.LampN19 = uilamp(app.UIFigure);
            app.LampN26 = uilamp(app.UIFigure);
            app.LampN23 = uilamp(app.UIFigure);
            app.LampN20 = uilamp(app.UIFigure);
            app.LampN27 = uilamp(app.UIFigure);
            app.LampN24 = uilamp(app.UIFigure);
            app.LampN21 = uilamp(app.UIFigure);
            app.LampN34 = uilamp(app.UIFigure);
            app.LampN31 = uilamp(app.UIFigure);
            app.LampN28 = uilamp(app.UIFigure);
            app.LampN35 = uilamp(app.UIFigure);
            app.LampN32 = uilamp(app.UIFigure);
            app.LampN29 = uilamp(app.UIFigure);
            app.LampN36 = uilamp(app.UIFigure);
            app.LampN33 = uilamp(app.UIFigure);
            app.LampN30 = uilamp(app.UIFigure);
            app.LampN43 = uilamp(app.UIFigure);
            app.LampN40 = uilamp(app.UIFigure);
            app.LampN37 = uilamp(app.UIFigure);
            app.LampN44 = uilamp(app.UIFigure);
            app.LampN41 = uilamp(app.UIFigure);
            app.LampN38 = uilamp(app.UIFigure);
            app.LampN45 = uilamp(app.UIFigure);
            app.LampN42 = uilamp(app.UIFigure);
            app.LampN39 = uilamp(app.UIFigure);
            app.LampN52 = uilamp(app.UIFigure);
            app.LampN49 = uilamp(app.UIFigure);
            app.LampN46 = uilamp(app.UIFigure);
            app.LampN53 = uilamp(app.UIFigure);
            app.LampN50 = uilamp(app.UIFigure);
            app.LampN47 = uilamp(app.UIFigure);
            app.LampN54 = uilamp(app.UIFigure);
            app.LampN51 = uilamp(app.UIFigure);
            app.LampN48 = uilamp(app.UIFigure);
            app.Button_F = uibutton(app.UIFigure, 'push');
            app.Button_F.ButtonPushedFcn = createCallbackFcn(app, @Button_FPushed, true);
            app.Button_F.Text = 'F';
            app.Button_R = uibutton(app.UIFigure, 'push');
            app.Button_R.ButtonPushedFcn = createCallbackFcn(app, @Button_RPushed, true);
            app.Button_R.Text = 'R';
            app.Button_U = uibutton(app.UIFigure, 'push');
            app.Button_U.ButtonPushedFcn = createCallbackFcn(app, @Button_UPushed, true);
            app.Button_U.Text = 'U';
            app.Button_L = uibutton(app.UIFigure, 'push');
            app.Button_L.ButtonPushedFcn = createCallbackFcn(app, @Button_LPushed, true);
            app.Button_L.Text = 'L';
            app.Button_B = uibutton(app.UIFigure, 'push');
            app.Button_B.ButtonPushedFcn = createCallbackFcn(app, @Button_BPushed, true);
            app.Button_B.Text = 'B';
            app.Button_D = uibutton(app.UIFigure, 'push');
            app.Button_D.ButtonPushedFcn = createCallbackFcn(app, @Button_DPushed, true);
            app.Button_D.Text = 'D';
            app.Button_F2 = uibutton(app.UIFigure, 'push');
            app.Button_F2.ButtonPushedFcn = createCallbackFcn(app, @Button_F2Pushed, true);
            app.Button_F2.Text = 'F''';
            app.Button_R2 = uibutton(app.UIFigure, 'push');
            app.Button_R2.ButtonPushedFcn = createCallbackFcn(app, @Button_R2Pushed, true);
            app.Button_R2.Text = 'R''';
            app.Button_U2 = uibutton(app.UIFigure, 'push');
            app.Button_U2.ButtonPushedFcn = createCallbackFcn(app, @Button_U2Pushed, true);
            app.Button_U2.Text = 'U''';
            app.Button_L2 = uibutton(app.UIFigure, 'push');
            app.Button_L2.ButtonPushedFcn = createCallbackFcn(app, @Button_L2Pushed, true);
            app.Button_L2.Text = 'L''';
            app.Button_B2 = uibutton(app.UIFigure, 'push');
            app.Button_B2.ButtonPushedFcn = createCallbackFcn(app, @Button_B2Pushed, true);
            app.Button_B2.Text = 'B''';
            app.Button_D2 = uibutton(app.UIFigure, 'push');
            app.Button_D2.ButtonPushedFcn = createCallbackFcn(app, @Button_D2Pushed, true);
            app.Button_D2.Text = 'D''';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.LsenButton = uibutton(app.UIFigure, 'state');
            app.LsenButton.ValueChangedFcn = createCallbackFcn(app, @LsenButtonValueChanged, true);
            app.LsenButton.Icon = fullfile(pathToMLAPP, 'assets', 'rubiks_icon.jpg');
            app.LsenButton.Text = 'Lösen';
            app.Losungsweg = uilabel(app.UIFigure);
            app.Losungsweg.Text = '';
            app.ScannenButton = uibutton(app.UIFigure, 'state');
            app.ScannenButton.ValueChangedFcn = createCallbackFcn(app, @ScannenButtonValueChanged, true);
            app.ScannenButton.Icon = fullfile(pathToMLAPP, 'assets', 'rubiks_icon.jpg');
            app.ScannenButton.Text = 'Scannen';
            app.RandomButton = uibutton(app.UIFigure, 'state');
            app.RandomButton.ValueChangedFcn = createCallbackFcn(app, @RandomButtonValueChanged, true);
            app.RandomButton.Text = 'Random';
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.RoboterSwitchLabel = uilabel(app.UIFigure);
            app.RoboterSwitchLabel.Text = 'Roboter';
            app.RoboterSwitch = uiswitch(app.UIFigure, 'slider');
            app.RoboterSwitch.Items = {'Aus', 'An'};
            app.RoboterSwitch.ValueChangedFcn = createCallbackFcn(app, @RoboterSwitchValueChanged, true);
            app.RoboterSwitch.Value = 'Aus';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cubeGUI_without_brick

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end