classdef lightGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        QuitButton        matlab.ui.control.Button
        ToggleModeButton  matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        ModeLabel         matlab.ui.control.Label
        ModeText          matlab.ui.control.Label
        FarbeLabel        matlab.ui.control.Label
        FarbeText         matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        config          % Speichern einiger Konfigurationsdaten
        brickObj        
        myTimer
    end
    
    methods (Access = private)
        function readLightTimerCallback(app, timerObj, event)
            maxValues = app.config.numberOfValues;
            
            userData = get(timerObj, 'UserData');
            currentMeasurementData = userData.measurementData;
            
            newMeasurementData = currentMeasurementData(2:end);
            
            newValue = app.brickObj.sensor1.value;
            newMeasurementData(maxValues) = newValue;
            userData.measurementData = newMeasurementData;
            set(timerObj, 'UserData', userData);
            
            plot(app.UIAxes, userData.measurementData);
            app.UIAxes.YLim = [0 100];
             
            farbe = 'Unbekannt';
            if newValue > 0 && newValue < 10
                farbe = 'blau/grün';
            elseif newValue > 40 && newValue < 50
                farbe = 'rot';
            elseif newValue > 70 && newValue < 90
                farbe = 'gelb';
            elseif newValue >= 90
                farbe = 'weiß';
            end
            
            app.FarbeText.Text = farbe;
        end
        
        function readLightTimerStartCallback(app, timerObj, event)
            disp('Timer wurde gestartet');
        end
        
        function readLightTimerStopCallback(app, timerObj, event)
            disp('Timer wurde angehalten');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Speichern einiger Konfigurationsdaten in den app-Properties
            app.config.numberOfValues       = 30;
            app.config.sensorMode           = 'reflect';
            app.config.timerPeriod          = 0.1;
            app.config.timerExecutionMode   = 'fixedDelay';
            app.config.brickConnectionType  = 'usb';
            
            % Bluetooth initialisieren und Verbindung oeffnen
            try
                app.brickObj = lightConnectEV3(app.config.brickConnectionType, ...
                    app.config.sensorMode);
            catch errinfo
                disp('Verbindung konnte nicht hergestellt werden');
                disp(errinfo);
            end
            
            % Daten fuer UserData Eigenschaft des Timer-Objektes vorbereiten
            myUserData.measurementData = zeros(app.config.numberOfValues,1);
            myUserData.brickObj = app.brickObj;
            
            % Timer-Objekt erzeugen, konfigurieren und in den app-Properties speichern
            app.myTimer = timer(...
                'Period',        app.config.timerPeriod, ...
                'ExecutionMode', app.config.timerExecutionMode, ...
                'UserData',      myUserData, ...
                'StartFcn',     @app.readLightTimerStartCallback, ...
                'StopFcn',      @app.readLightTimerStopCallback, ...
                'TimerFcn',     @app.readLightTimerCallback);
            
            % Den aktuellen Mode in der GUI anzeigen
            app.ModeText.Text = app.config.sensorMode;
            app.FarbeText.Text = 'Keine';
            
            % Initiales Plotten der Daten (muss man nicht unbedingt machen)
            plot(app.UIAxes, myUserData.measurementData);
            app.UIAxes.YLim = [0 100];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.myTimer);
            app.brickObj.disconnect();
            delete(app.myTimer);
            % Hint: delete(app) closes the figure
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.myTimer.Running, 'off')
                start(app.myTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if strcmp(app.myTimer.Running, 'on')
                stop(app.myTimer);
            end
        end

        % Button pushed function: ToggleModeButton
        function ToggleModeButtonPushed(app, event)
            userData = get(app.myTimer, 'UserData');
            if strcmp(app.config.sensorMode, 'reflect')
                app.config.sensorMode = 'ambient';
                userData.brickObj.sensor1.mode = DeviceMode.Color.Ambient;
            else
                app.config.sensorMode = 'reflect';
                userData.brickObj.sensor1.mode = DeviceMode.Color.Reflect;
            end
            app.ModeText.Text = app.config.sensorMode;
        end

        % Callback function: QuitButton, UIFigure
        function QuitButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ToggleModeButton = uibutton(app.UIFigure, 'push');
            app.ToggleModeButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleModeButtonPushed, true);
            app.ToggleModeButton.Text = 'Toggle Mode';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode:';
            app.ModeText = uilabel(app.UIFigure);
            app.ModeText.Text = '';
            app.FarbeLabel = uilabel(app.UIFigure);
            app.FarbeLabel.Text = 'Farbe:';
            app.FarbeText = uilabel(app.UIFigure);
            app.FarbeText.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lightGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end